classdef Corpuz_Llovit_MatLab_LBYDSP_Audio_Equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CorpuzLlovitAudioEqualizerUIFigure  matlab.ui.Figure
        BrowseButton         matlab.ui.control.Button
        EqualizerPanel       matlab.ui.container.Panel
        PreampSlider_2       matlab.ui.control.Slider
        PreampSlider_2Label  matlab.ui.control.Label
        KHzSlider_5          matlab.ui.control.Slider
        KHzSlider_5Label     matlab.ui.control.Label
        KHzSlider_4          matlab.ui.control.Slider
        KHzSlider_4Label     matlab.ui.control.Label
        KHzSlider_3          matlab.ui.control.Slider
        KHzSlider_3Label     matlab.ui.control.Label
        KhzSlider            matlab.ui.control.Slider
        KhzSliderLabel       matlab.ui.control.Label
        KHzSlider_2          matlab.ui.control.Slider
        KHzSlider_2Label     matlab.ui.control.Label
        KHzSlider            matlab.ui.control.Slider
        KHzSliderLabel       matlab.ui.control.Label
        HzSlider_3           matlab.ui.control.Slider
        HzSlider_3Label      matlab.ui.control.Label
        HzSlider_2           matlab.ui.control.Slider
        HzSlider_2Label      matlab.ui.control.Label
        HzSlider             matlab.ui.control.Slider
        HzSliderLabel        matlab.ui.control.Label
        PresetDropDown       matlab.ui.control.DropDown
        PresetDropDownLabel  matlab.ui.control.Label
        PreampSlider         matlab.ui.control.Slider
        PreampSliderLabel    matlab.ui.control.Label
        WaveformPanel        matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        PlayButton           matlab.ui.control.Button
        BrowseSong           matlab.ui.control.TextArea
        Image                matlab.ui.control.Image
    end

    
   properties (Access = private)
    audioData       % Audio data
    samplingRate    % Sampling frequency
    player          % audioplayer object
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseButton, BrowseSong
        function BrowseButtonPushed(app, event)
            pause(app.player);
             app.PlayButton.Text = 'Play';
            
            [file, path] = uigetfile({'*.mp3;*.wav', 'Audio Files (*.mp3, *.wav)'});
            if isequal(file, 0)
                return; % User canceled the file selection
            end
            [app.audioData, app.samplingRate] = audioread(fullfile(path, file));
            plot(app.UIAxes, (1:length(app.audioData))/app.samplingRate, app.audioData, ":");
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Audio Waveform');
            app.player = audioplayer(app.audioData, app.samplingRate);
            app.BrowseSong.Value = sprintf('%s', file);

           
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % if strcmp(app.audioPlayer.Running, 'off')
            %     play(app.audioPlayer);
            %     startSpectrumAnalysis(app);
            %     app.PauseButton.Visible = 'on';
            %     app.PlayButton.Visible = 'off';
            % end

            if isempty(app.player)
                return;
            end

            if strcmp(app.player.running, 'on')
                  pause(app.player);
                  app.PlayButton.Text = 'Play';
              else
                  resume(app.player);
                  app.PlayButton.Text = 'Pause';
             end
        end

        % Callback function: not associated with a component
        function SettingsButtonPushed(app, event)
            app.CorpuzLlovitAudioEqualizerUIFigure.Position = [300 200 983 660];
            app.Image.Position = [0 0 983 660];

            app.BrowseSong.Position = [99 621 532 33];
            app.BrowseButton.Position = [12 624 74 23];
            app.PlayButton.Position = [290 32 61 23];
            app.WaveformPanel.Position = [200 250 618 255];
            app.UIAxes.Position = [12 30 590 203];
            app.Label.Position = [1 31 25 22];
            app.PositionSlider.Position = [47 40 561 3];
        end

        % Callback function: not associated with a component
        function PauseButtonPushed(app, event)
            if ~isempty(app.player)
                stop(app.player);
                app.PauseButton.Visible = 'off';
                app.PlayButton.Visible = 'on';
            end
            
            
            % if ~isempty(app.audioPlayer) && strcmp(app.audioPlayer.Running, 'on')
            %     pause(app.audioPlayer);
            %     stopSpectrumAnalysis(app);
            %     app.PlayButton.Visible = 'on';
            %     app.PauseButton.Visible = 'off';
            % end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CorpuzLlovitAudioEqualizerUIFigure = uifigure('Visible', 'off');
            app.CorpuzLlovitAudioEqualizerUIFigure.Name = 'Corpuz-Llovit Audio Equalizer';
            app.CorpuzLlovitAudioEqualizerUIFigure.Icon = fullfile(pathToMLAPP, 'audio-equalizer.512x506.png');
            app.Image = uiimage(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Fruitger_Aero_BG.png');
            app.BrowseSong = uitextarea(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.BrowseSong.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseSong.Editable = 'off';
            app.BrowseSong.Tooltip = {'Displays the current song playing'};
            app.BrowseSong.Value = {'Browse a song to play'};
            app.PlayButton = uibutton(app.CorpuzLlovitAudioEqualizerUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.WaveformPanel = uipanel(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.WaveformPanel.Tooltip = {'Visual Aid to see the Waveforms of the Audio File'};
            app.WaveformPanel.Title = 'Waveform';
            app.WaveformPanel.Tag = 'Panel Waveform';
            app.UIAxes = uiaxes(app.WaveformPanel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes.YTickLabel = {'0%'; '20%'; '40%'; '60%'; '80%'; '1'};
            app.EqualizerPanel = uipanel(app.CorpuzLlovitAudioEqualizerUIFigure);
            app.EqualizerPanel.Title = 'Equalizer';
            app.PreampSliderLabel = uilabel(app.EqualizerPanel);
            app.PreampSliderLabel.Text = 'Preamp';
            app.PreampSlider = uislider(app.EqualizerPanel);
            app.PreampSlider.Limits = [-12 12];
            app.PreampSlider.MajorTicks = [-12 0 12];
            app.PreampSlider.MajorTickLabels = {'-12 dB', '0 dB', '12 dB'};
            app.PresetDropDownLabel = uilabel(app.EqualizerPanel);
            app.PresetDropDownLabel.Text = 'Preset';
            app.PresetDropDown = uidropdown(app.EqualizerPanel);
            app.HzSliderLabel = uilabel(app.EqualizerPanel);
            app.HzSliderLabel.Text = '170 Hz';
            app.HzSlider = uislider(app.EqualizerPanel);
            app.HzSlider.Limits = [-12 12];
            app.HzSlider.MajorTicks = [-12 0 12];
            app.HzSlider.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.HzSlider_2Label = uilabel(app.EqualizerPanel);
            app.HzSlider_2Label.Text = '310 Hz';
            app.HzSlider_2 = uislider(app.EqualizerPanel);
            app.HzSlider_2.Limits = [-12 12];
            app.HzSlider_2.MajorTicks = [-12 0 12];
            app.HzSlider_2.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.HzSlider_3Label = uilabel(app.EqualizerPanel);
            app.HzSlider_3Label.Text = '600 Hz';
            app.HzSlider_3 = uislider(app.EqualizerPanel);
            app.HzSlider_3.Limits = [-12 12];
            app.HzSlider_3.MajorTicks = [-12 0 12];
            app.HzSlider_3.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KHzSliderLabel = uilabel(app.EqualizerPanel);
            app.KHzSliderLabel.Text = '1 KHz';
            app.KHzSlider = uislider(app.EqualizerPanel);
            app.KHzSlider.Limits = [-12 12];
            app.KHzSlider.MajorTicks = [-12 0 12];
            app.KHzSlider.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KHzSlider_2Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_2Label.Text = '3 KHz';
            app.KHzSlider_2 = uislider(app.EqualizerPanel);
            app.KHzSlider_2.Limits = [-12 12];
            app.KHzSlider_2.MajorTicks = [-12 0 12];
            app.KHzSlider_2.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KhzSliderLabel = uilabel(app.EqualizerPanel);
            app.KhzSliderLabel.Text = '6 Khz';
            app.KhzSlider = uislider(app.EqualizerPanel);
            app.KhzSlider.Limits = [-12 12];
            app.KhzSlider.MajorTicks = [-12 0 12];
            app.KhzSlider.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KHzSlider_3Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_3Label.Text = '12 KHz';
            app.KHzSlider_3 = uislider(app.EqualizerPanel);
            app.KHzSlider_3.Limits = [-12 12];
            app.KHzSlider_3.MajorTicks = [-12 0 12];
            app.KHzSlider_3.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KHzSlider_4Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_4Label.Text = '14 KHz';
            app.KHzSlider_4 = uislider(app.EqualizerPanel);
            app.KHzSlider_4.Limits = [-12 12];
            app.KHzSlider_4.MajorTicks = [-12 0 12];
            app.KHzSlider_4.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.KHzSlider_5Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_5Label.Text = '16 KHz';
            app.KHzSlider_5 = uislider(app.EqualizerPanel);
            app.KHzSlider_5.Limits = [-12 12];
            app.KHzSlider_5.MajorTicks = [-12 0 12];
            app.KHzSlider_5.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.PreampSlider_2Label = uilabel(app.EqualizerPanel);
            app.PreampSlider_2Label.Text = 'Preamp';
            app.PreampSlider_2 = uislider(app.EqualizerPanel);
            app.PreampSlider_2.Limits = [-12 12];
            app.PreampSlider_2.MajorTicks = [-12 0 12];
            app.PreampSlider_2.MajorTickLabels = {'-12 db', '0 dB', '12 dB'};
            app.BrowseButton = uibutton(app.CorpuzLlovitAudioEqualizerUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = fullfile(pathToMLAPP, 'Aero Circle 08.png');
            app.BrowseButton.Tooltip = {'Browse songs in your current file'};
            app.BrowseButton.Text = 'Browse';
            % Show the figure after all components are created
            app.CorpuzLlovitAudioEqualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Corpuz_Llovit_MatLab_LBYDSP_Audio_Equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CorpuzLlovitAudioEqualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CorpuzLlovitAudioEqualizerUIFigure)
        end
    end
end